classdef getVesselWidths < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        fittypeDropDown             matlab.ui.control.DropDown
        fittypeDropDownLabel        matlab.ui.control.Label
        toggleoxygenationCheckBox   matlab.ui.control.CheckBox
        bLoadOxygenationMapButton   matlab.ui.control.Button
        BinFramesEditField          matlab.ui.control.NumericEditField
        BinFramesEditFieldLabel     matlab.ui.control.Label
        OuterPixelsEditField        matlab.ui.control.NumericEditField
        OuterPixelsEditFieldLabel   matlab.ui.control.Label
        AdjustAllvesselsPanel       matlab.ui.container.Panel
        Button_7                    matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        cLoadPreviousVesselsButton  matlab.ui.control.Button
        LoadImageFileButton         matlab.ui.control.Button
        EditCurrentVesselPanel      matlab.ui.container.Panel
        VesselTypeDropDown          matlab.ui.control.DropDown
        VesselTypeDropDownLabel     matlab.ui.control.Label
        RedrawButton                matlab.ui.control.Button
        DeleteButton                matlab.ui.control.Button
        VesselDropDown              matlab.ui.control.DropDown
        AddVesselButton             matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        GetVesselWidthsButton       matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dir %base directory
        filename %filename of tif file
        numChannels %number of color channels in tif file
        vesselChannel %color channel of vessel images

        imSize %image size [H W F]
        imageLoaded %whether image file has been loaded
        meanImage %mean projection of vessel image

        oxygenationLoaded %whether a valid oxygenation file has been loaded
        oxyImage %red/blue image of the vessel oxygenation file
        
        vesselBoxes %struct to store vessel box segment information
        currentVessel %currently selected vessel box
    end
    
    methods (Access = private)
        
        function updateUIAxis(app)
            cla(app.UIAxes)
            if app.toggleoxygenationCheckBox.Value
                imshow(app.oxyImage,'Parent',app.UIAxes)
            else
                imshow(app.meanImage,'Parent',app.UIAxes)
            end
            hold(app.UIAxes,'on');

            %draw all vessel boxes made so far
            op = app.OuterPixelsEditField.Value;
            n = app.vesselBoxes.num;
            if n>0
                %draw boxes (and outer pixel regions)
                color = hsv(n);
                for r=1:n
                    plot(app.vesselBoxes.x(r,:),app.vesselBoxes.y(r,:),'Color',color(r,:),'Parent',app.UIAxes,'LineWidth',2);

                    %get vessel length and angle
                    dx = mean(app.vesselBoxes.x(r,[2 3])) - mean(app.vesselBoxes.x(r,[1 4]));
                    dy = mean(app.vesselBoxes.y(r,[2 3])) - mean(app.vesselBoxes.y(r,[1 4]));
                    vesselLength = sqrt(dx^2 + dy^2);
                    vesselAngle = atan2(dy,dx);

                    %get vessel width
                    dx = mean(app.vesselBoxes.x(r,[1 2])) - mean(app.vesselBoxes.x(r,[3 4]));
                    dy = mean(app.vesselBoxes.y(r,[1 2])) - mean(app.vesselBoxes.y(r,[3 4]));
                    vesselWidth = sqrt(dx^2 + dy^2);
                    
                    %get vessel center
                    cx = mean(app.vesselBoxes.x(r,:));
                    cy = mean(app.vesselBoxes.y(r,:));
                    
                    %get vessel start and end centers
                    ecx = mean(app.vesselBoxes.x(r,[2 3]));
                    scx = mean(app.vesselBoxes.x(r,[1 4]));
                    ecy = mean(app.vesselBoxes.y(r,[2 3]));
                    scy = mean(app.vesselBoxes.y(r,[1 4]));

                    %get top outer pixel coordinates
                    eopx = ecx + ((vesselWidth/2) + op)*cos(vesselAngle + (pi/2));
                    sopx = scx + ((vesselWidth/2) + op)*cos(vesselAngle + (pi/2));
                    eopy = ecy + ((vesselWidth/2) + op)*sin(vesselAngle + (pi/2));
                    sopy = scy + ((vesselWidth/2) + op)*sin(vesselAngle + (pi/2));
                    plot([sopx eopx],[sopy eopy],'k','Color',color(r,:),'Parent',app.UIAxes,'LineWidth',1)

                    %get bottom outer pixel coordinates
                    eopx = ecx - ((vesselWidth/2) + op)*cos(vesselAngle + (pi/2));
                    sopx = scx - ((vesselWidth/2) + op)*cos(vesselAngle + (pi/2));
                    eopy = ecy - ((vesselWidth/2) + op)*sin(vesselAngle + (pi/2));
                    sopy = scy - ((vesselWidth/2) + op)*sin(vesselAngle + (pi/2));
                    plot([sopx eopx],[sopy eopy],'k','Color',color(r,:),'Parent',app.UIAxes,'LineWidth',1)

                    app.Label.Text = ['angle: ' num2str(vesselAngle) ', width: ' num2str(vesselWidth)];
                end

                %draw text for the currently selected vessel
                text(mean(app.vesselBoxes.x(app.currentVessel,:)),...
                    mean(app.vesselBoxes.y(app.currentVessel,:)),...
                    num2str(app.currentVessel),'Color',color(app.currentVessel,:),'Parent',app.UIAxes,'HorizontalAlignment','center');
            end
            drawnow

            %update vessel dropdown
            if n>0
                labels = cellstr(num2str((1:n)'));
                app.VesselDropDown.Items = labels;
                app.VesselDropDown.ItemsData = labels;
                app.VesselDropDown.Value = labels{app.currentVessel};
                app.VesselTypeDropDown.Value = app.vesselBoxes.type{app.currentVessel};
            else
                app.VesselDropDown.Items = {''};
                app.VesselDropDown.ItemsData = [];
                app.VesselDropDown.Value = [];
                app.VesselTypeDropDown.Value = '?';
            end

            figure(app.UIFigure)
        end
        
        function y = gauss2dc(x,a1,b1,c1,a2,b2,c2)
            % FUNCTION y = gauss2dc(x,A,B,C,D)
            % evaluate a discontinuous two-term gaussian function with an undefined center
            
            y = a1*exp(-((x-b1)/c1).^2) + a2*exp(-((x-(b1+b2))/c2).^2);
            y(x>b1 & x<(b1+b2)) = nan; %remove center
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageFileButton
        function LoadImageFileButtonPushed(app, event)
            %get image series filename and directory
            [file, path] = uigetfile('*.tif','Choose .tif file to calculate vessel widths');
            
            if exist(fullfile(path,file),'file')
                app.dir = path;
                [~, app.filename, ~] = fileparts(file);
                source = fullfile(path,file);

                app.Label.Text = 'Getting file info...';
                info = imfinfo(source);
                im_size = [info(1).Height, info(1).Width, length(info)];
                app.imSize = im_size;
                if im_size(3)==1
                    %for single frame tif files, just load the frame
                    app.Label.Text = 'The selected .tif file only contains 1 frame.';
                    pause(1) %pause for message to be read

                    %load image
                    app.numChannels = 1;
                    app.vesselChannel = 1;
                    mI = double(imread(source,1));
                    mI = mI - min(mI,[],'all','omitnan');
                    mI = mI/prctile(mI,99,'all');

                    %set bin frames to 1
                    app.BinFramesEditField.Value = 1;
                    app.BinFramesEditField.Enable = 'off';

                else
                    num_frames_to_load = min([16 im_size(3)]);

                    %for multi-frame tif files, load the 1st 16 frames, or
                    %load all frames, whichever is smalled
                    iseries = nan([im_size(1:2) num_frames_to_load]);
                    app.Label.Text = 'Loading images...';
                    for f = 1:num_frames_to_load
                        iseries(:,:,f) = imread(source,f);
                    end
                    app.Label.Text = '';
    
                    %use image correlation to determine if this is a
                    %multichannel tif stack
                    R = nan(4,num_frames_to_load);
                    for f1 = 1:4
                        for f2 = 1:num_frames_to_load
                            R(f1,f2) = corr2(iseries(:,:,f1),iseries(:,:,f2));
                        end
                    end
                    if mean(R,'all')>0.8
                        nC = 1;
                    else
                        Y = mean(fft(R'),2);
                        P2 = abs(Y/num_frames_to_load);
                        P1 = P2(1:(1+num_frames_to_load/2));
                        P1(2:end-1) = 2*P1(2:end-1);
                    
                        [~,I] = max(P1(2:end));
                        Period = num_frames_to_load./(1:(num_frames_to_load/2));
                        if isempty(I)
                            nC = 1;
                        else
                            nC = round(Period(I));
                            if nC>8
                                nC = 4;
                            end
                        end
                    end
    
                    %ask user if this is a multichannel dataset
                    opts.Resize = 'on';
                    answer = inputdlg({'Number of channels','Vessel channel'},...
                        'Is this a multichannel tif file?', [1 75; 1 75],...
                        {num2str(nC), num2str(nC)},opts);
                    app.numChannels = round(str2double(answer{1}));
                    app.vesselChannel = round(str2double(answer{2}));
                    if (app.numChannels<1) || (app.numChannels>8)
                        app.Label.Text = 'Number of channels must be between 1-8';
                        error('Number of channels must be between 1-8')
                    end
                    if (app.vesselChannel>app.numChannels) || (app.vesselChannel<1)
                        app.Label.Text = 'Vessel channel # must be between 1 and total number of channels in tif file';
                        error('Vessel channel # must be between 1 and total number of channels in tif file')
                    end
                    
                    %load subset of frames of this channel
                    if app.numChannels>1
                        for f = app.vesselChannel:app.numChannels:num_frames_to_load*app.numChannels
                            iseries(:,:,f) = imread(source,f);
                        end
                    end
    
                    %display mean projection
                    mI = mean(iseries,3,'omitnan');
                    mI = mI - min(mI,[],'all','omitnan');
                    mI = mI/prctile(mI,99,'all');

                    %set bin frames to 1
                    app.BinFramesEditField.Value = 1;
                    app.BinFramesEditField.Enable = 'on';
                end

                %store main image (mean of subset, or single frame)
                app.meanImage = mI;

                %clear current vessel boxes
                app.vesselBoxes.x = [];
                app.vesselBoxes.y = [];
                app.vesselBoxes.num = 0;
                app.vesselBoxes.type = {};

                app.imageLoaded = true;
                app.updateUIAxis;
            end
        end

        % Button pushed function: AddVesselButton
        function AddVesselButtonPushed(app, event)
            if app.imageLoaded
                app.Label.Text = 'Waiting for user-drawn line...';
            
                %get user's screen size to create a large figure window
                screenSize = get(groot,'ScreenSize');
                left = screenSize(3)*0.2;
                bottom = screenSize(4)*0.1;
                width = screenSize(3)*0.6;
                height = screenSize(4)*0.8;
                
                %create new figure window for drawing a vessel box
                drawfigure = figure('Name','draw vessel box','NumberTitle','off');
                drawaxis = axes(drawfigure);
                imshow(app.meanImage,'Parent',drawaxis);
                hold on
                set(drawfigure,'Position',[left bottom width height]);
                set(drawaxis,'OuterPosition',[0 0 1 1]);
                title(drawaxis,'Draw a box for a vessel edge segment (double-click starting vertex to finish)');
                
                %draw all vessel boxes made so far
                n = app.vesselBoxes.num;
                if n>0
                    color = hsv(n);
                    for r=1:n
                        plot(app.vesselBoxes.x(r,:),app.vesselBoxes.y(r,:),'Color',color(r,:),'Parent',drawaxis,'LineWidth',2);
                    end
                end
                drawnow
                
                %have user draw a new ROI polygon
                try
                    [~, x, y] = roipoly;
                catch %if roipoly figure was exited incorrectly
                    app.Label.Text = '"Add vessel" exited';
                    return
                end
                try
                    close(drawfigure)
                catch
                    app.Label.Text = '"Add vessel" exited';
                    return
                end
                if exist('x','var') && isempty(x) %if no ROI is drawn
                    app.Label.Text = 'no line drawn';
                    return;
                end
                if length(x)~=5 % 5th value is the same as the 1st, so essentially this is just 4 points
                    app.Label.Text = '! no vessel box added -- vessel box must be exactly 4 points !';
                    return;
                end
    
                app.vesselBoxes.num = app.vesselBoxes.num+1;
                n = app.vesselBoxes.num;
                app.currentVessel = n;
               
                %add new ROI to full list
                app.vesselBoxes.x(n,:) = x;
                app.vesselBoxes.y(n,:) = y;
                app.vesselBoxes.type{n} = '?';
                
                app.Label.Text = '';
                app.updateUIAxis;

            else
                app.Label.Text = '! No image file has been loaded !';
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            cv = str2double(app.VesselDropDown.Value);
            if isempty(cv)
                app.Label.Text = 'Cannot delete: no vessel box selected';
                pause(2); %pause for message to be read
                app.Label.Text = '';
            else %delete the selected vessel box
                app.vesselBoxes.num = app.vesselBoxes.num - 1;
                app.vesselBoxes.x(cv,:) = [];
                app.vesselBoxes.y(cv,:) = [];
                app.vesselBoxes.type(cv) = [];
                app.currentVessel = app.vesselBoxes.num ;
                
                app.updateUIAxis;
            end
        end

        % Button pushed function: GetVesselWidthsButton
        function GetVesselWidthsButtonPushed(app, event)
            if app.imageLoaded

                bf = app.BinFramesEditField.Value;
                op = app.OuterPixelsEditField.Value;
                nv = app.vesselBoxes.num;
                if nv>0
                    
                    %calculate vessel diameters for each vessel box
                    nf = floor(app.imSize(3)/app.numChannels);
                    if bf>nf
                        app.Label.Text = '! Bin Frames must be less than the total number of frames !';
                        return
                    end

                    vesselWidths = nan(nv, nf);
                    vesselProfiles = nan(nv, 1, 2); %[vessel, width(pixels), actualOrFit]
                    vesselEdges = nan(nv,2); %[vessel, left/right edge]
                    correlationCoefficients = nan(nv, nf);
                    waitfig = waitbar(0,'Calculating vessel widths');
                    for v = 1:nv
                        waitbar((v-1)/nv,waitfig,'Calculating vessel widths')
                        invert = false;
    
                        %calculate vessel box position
                        centerx = round(mean(app.vesselBoxes.x(v,1:4)));
                        centery = round(mean(app.vesselBoxes.y(v,1:4)));
    
                        %get vessel length and angle
                        dx = mean(app.vesselBoxes.x(v,[2 3])) - mean(app.vesselBoxes.x(v,[1 4]));
                        dy = mean(app.vesselBoxes.y(v,[2 3])) - mean(app.vesselBoxes.y(v,[1 4]));
                        vesselLength = sqrt(dx^2 + dy^2);
                        vesselAngle = atan2(dy,dx);
    
                        %get vessel width
                        dx = mean(app.vesselBoxes.x(v,[1 2])) - mean(app.vesselBoxes.x(v,[3 4]));
                        dy = mean(app.vesselBoxes.y(v,[1 2])) - mean(app.vesselBoxes.y(v,[3 4]));
                        vesselWidth = sqrt(dx^2 + dy^2);
                        
                        %get crop box around vessel
                        minx = (app.imSize(2)/2) - (vesselLength/2);
                        miny = (app.imSize(1)/2) - (vesselWidth);
                        rect = round([minx miny vesselLength vesselWidth+(2*op)]);
                        
                        %store average vessel profile (actual and fit)
                        vesselProfile = zeros(1, round(2*vesselWidth), 2);
                        %store estimated vessel edge locations
                        vesselEdge = zeros(1, 2);
                        numavgs = 0;
                        for f = 1:bf:nf
                            waitbar(((v-1)/nv) + ((f-1)/(nf*nv)),waitfig,'Calculating vessel widths')

                            f_ind = app.vesselChannel + ((f-1)*app.numChannels);
                            if bf>1
                                bfis = f:(f+bf-1);
                                f_inds = app.vesselChannel + ((bfis-1)*app.numChannels);
                                f_inds(f_inds>app.imSize(3)) = [];
                                I = nan([app.imSize(1:2) length(f_inds)]);
                                for bfi = 1:length(f_inds)
                                    I(:,:,bfi) = imread(fullfile(app.dir,[app.filename '.tif']),f_inds(bfi));
                                end
                                I = mean(I,3,'omitnan');
                            else
                                I = imread(fullfile(app.dir,[app.filename '.tif']),f_ind);
                            end

                            %center, rotate, and crop around vessel
                            I = double(I);
                            I = I - min(I,[],'all');
                            I = I/max(I,[],'all');
                            I = imtranslate(I, [((app.imSize(2)/2)-centerx), ((app.imSize(1)/2)-centery)]);  % center on vessel
                            I = imrotate(I,rad2deg(vesselAngle),'crop');  % rotate so the vessel is horizontal
                            I = imcrop(I,rect); %crop around vessel box
    
                            %average vessel along length
                            y = mean(I,2);
                            y = y-min(y);
                            y = y/max(y);
                            nl = length(y);
    
                            %if outside of vessel is brighter than the inside, invert brightness
                            if f==1
                                outside_brightness = mean(y([1:round(nl/4) round(3*nl/4):end]));
                                inside_brightness = mean(y(round(nl/4):round(3*nl/4)));
                                if outside_brightness > inside_brightness
                                    invert = true; %if the 1st frame gets inverted, invert all frames to stay consistent
                                end
                            end
                            if invert
                                y = 1-y; 
                            end
                            x = (1:nl)'; %create vessel profile vector (in pixels)

                            % fit a curve to the vessel profile
                            switch app.fittypeDropDown.Value
                                case 'gaussian'
                                    gaussEqn = 'a*exp(-((x-b)/c)^2)';
                                    upper = [1.5 max(x) 0.3*length(x)];
                                    lower = [0.25 min(x) 0.01*length(x)];
                                    if f>1 && cc(1,2)>0.95 %if the previous loop found a good fit, use that as a starting point
                                        startpoints = [fr.a fr.b fr.c];
                                        fo = fitoptions('Method','NonlinearLeastSquares','StartPoint',startpoints,'Upper',upper,'Lower',lower);
                                    else
                                        startpoints = [1 mean(x) 0.1*length(x)];
                                        fo = fitoptions('Method','NonlinearLeastSquares','StartPoint',startpoints,'Upper',upper,'Lower',lower);
                                    end
                                    fr = fit(x,y,gaussEqn,fo);
                                    %evaluate fit
                                    yvec = fr.a*exp(-((x-fr.b)/fr.c).^2);
                                    %find edges by x-locations of left and right half-max of each gaussian term separately)
                                    left_edge = fr.b-fr.c*sqrt(-log(0.5));
                                    right_edge = fr.b+fr.c*sqrt(-log(0.5));

                                case '2-term gaussian'
                                    if f>1 && cc(1,2)>0.95 %if the previous loop found a good fit, use that as a starting point
                                        startpoints = [fr.a1 fr.b1 fr.c1 fr.a2 fr.b2 fr.c2];
                                        fo = fitoptions('Method','NonlinearLeastSquares','StartPoint',startpoints);
                                        fr = fit(x,y,'gauss2',fo);
                                    else
                                        fr = fit(x,y,'gauss2');
                                    end
                                    %evaluate fit
                                    yvec = fr.a1*exp(-((x-fr.b1)/fr.c1).^2) + fr.a2*exp(-((x-fr.b2)/fr.c2).^2);
                                    %find edges by x-locations of left and right half-max of each gaussian term separately)
                                    left_edge = min([fr.b1-fr.c1*sqrt(-log(0.5)), fr.b2-fr.c2*sqrt(-log(0.5))]);
                                    right_edge = max([fr.b1+fr.c1*sqrt(-log(0.5)), fr.b2+fr.c2*sqrt(-log(0.5))]);

                                otherwise
                                    app.Label.Text = 'fit type not recognized';
                                    return
                            end
                            
                            %get correlation coefficient between profile and fit
                            cc = corrcoef(y,yvec);
                            correlationCoefficients(v,f) = cc(1,2);

                            %store vessel profiles in average
                            numavgs = numavgs+1;
                            if nl>size(vesselProfile,2)
                                vesselProfile(:,size(vesselProfile,2)+1:nl,:) = 0;
                            end
                            vesselProfile(1,1:nl,1) = (vesselProfile(1,1:nl,1)*(numavgs-1)/numavgs) + (y'/numavgs);
                            vesselProfile(1,1:nl,2) = (vesselProfile(1,1:nl,2)*(numavgs-1)/numavgs) + (yvec'/numavgs);
                            vesselWidths(v,f) = right_edge - left_edge;

                            %store vessel edges in average
                            vesselEdge = (vesselEdge*(numavgs-1)/numavgs) + ([left_edge right_edge]./numavgs);
                        end
                        vesselProfile_length = size(vesselProfile,2);
                        if vesselProfile_length>size(vesselProfiles,2)
                            vesselProfiles(:, size(vesselProfiles,2)+1:vesselProfile_length,:) = nan;
                        end
                        vesselProfiles(v, 1:vesselProfile_length, :) = vesselProfile;
                        vesselEdges(v,:) = vesselEdge;
                    end

                    close(waitfig)
                    
                    %fill missing values
                    if bf>1
                        vesselWidths = fillmissing(vesselWidths,'spline',2,'MissingLocations',isnan(vesselWidths),'EndValues','nearest');
                    end

                    %save .mat files of vessel boxes and vessel width vectors
                    app.Label.Text = ['Saving files in ' app.dir '...'];
                    vesselBoxes = app.vesselBoxes;
                    save(fullfile(app.dir,['vesselWidths_' app.filename '.mat']),'vesselBoxes','vesselWidths','correlationCoefficients');
    
                    %get screen size for creating figure windows
                    screenSize = get(groot,'ScreenSize');
                    left = screenSize(3)*0.1;
                    bottom = screenSize(4)*0.1;
                    width = screenSize(3)*0.8;
                    height = 0.5*width;
    

                    %create a figure of all vessel gaussian fits
                    figure0 = figure('Position',[left bottom width height]);
                    npw = ceil(sqrt(nv));
                    nph = ceil(nv/npw);
                    for v = 1:nv
                        subplot(nph,npw,v)
                        plot(vesselProfiles(v,:,1),'k','LineWidth',2)
                        hold on
                        plot(vesselProfiles(v,:,2),'--b','LineWidth',2)
                        plot([vesselEdges(v,1) vesselEdges(v,1)],[min(vesselProfiles(v,:,2)) max(vesselProfiles(v,:,2))],'g','LineWidth',2)
                        plot([vesselEdges(v,2) vesselEdges(v,2)],[min(vesselProfiles(v,:,2)) max(vesselProfiles(v,:,2))],'g','LineWidth',2)
                        title(['vessel ' num2str(v) ' (cc=' num2str(mean(correlationCoefficients(v,:))) ')'])
                        xlabel('vessel cross section (pixels)')
                        ylabel('brightness')
                        if v==1
                            legend({'mean vessel profile','mean gaussian fit','mean edges from fits'})
                        end
                    end
                    saveas(figure0,fullfile(app.dir,['VesselFits_' app.filename '.svg']));

                    %create a figure of all drawn vessel boxes
                    figure1 = figure('Position',[left bottom width height]);
                    subplot(1,2,1)
                    axis1 = gca;
                    imshow((app.meanImage-0.05)*2,'Parent',axis1); %print mean image
                    set(figure1,'Position',[left bottom width height]);
                    set(axis1,'OuterPosition',[0 0 0.5 1]);
                    drawnow
                    hold(axis1,'on');
                    axis off
                    lw = 2;
                    colors = hsv(nv);
                    for v=1:nv %draw all ROIs
                        plot(app.vesselBoxes.x(v,:),app.vesselBoxes.y(v,:),'Color',colors(v,:),'Parent',axis1,'LineWidth',lw);
                    end
                    pause(0.1)
    
                    %create a figure of all vessel diameter values
                    subplot(1,2,2)
                    axis2 = gca;
                    % axis2 = axes('Parent',figure2);
                    lw = 2;
                    colors = hsv(nv);

                    if nf==1
                        %for single frame tif files, make scatterplot of
                        %all vessels and diameters
                        for v=1:nv %draw all ROIs
                            scatter(v,vesselWidths(v),20,'filled','MarkerFaceColor',colors(v,:),'MarkerEdgeColor','none')
                            hold on
                        end
                        xticks(1:nv)
                        xlim([0.5 nv+0.5])
                        ylabel('Vessel Width (pixels)')
                        xlabel('Vessel Number')
                        
                    else
                        %for multi-frame tif files, draw line plots for all
                        %vessel diameters
                        for v=1:nv %draw all ROIs
                            plot(vesselWidths(v,:),'Color',colors(v,:),'Parent',axis2,'LineWidth',lw);
                            hold on
                        end
                        labels = cellstr(num2str((1:nv)'));
                        legend(labels,'Parent',figure1,'Location','northeastoutside')
                        ylabel('Vessel Width (pixels)')
                        xlabel('Frame Number')
                    end
                    
                    pause(0.1)
                    saveas(figure1,fullfile(app.dir,['VesselWidths_' app.filename '.svg']));
    
                    app.delete;
                else
                    app.Label.Text = '! No vessel boxes to calculate !';
                end

            else
                app.Label.Text = '! No image file has been loaded !';
            end
        end

        % Button pushed function: cLoadPreviousVesselsButton
        function cLoadPreviousVesselsButtonPushed(app, event)
            if app.imageLoaded
                %get mat file filename and directory
                [file, path] = uigetfile(fullfile(app.dir,'*.mat'),'Choose .mat file to load vessel boxes');
                source = fullfile(path,file);
    
                if exist(fullfile(path,file),'file')
                    load(source,'vesselBoxes')
                    if exist('vesselBoxes','var')
                        app.vesselBoxes = vesselBoxes;
                        if ~isfield(vesselBoxes,'type')
                            for i = 1:vesselBoxes.num
                                app.vesselBoxes.type{i} = '?';
                            end
                        end
                        app.currentVessel = vesselBoxes.num;
    
                        app.updateUIAxis;
                    else
                        app.Label.Text = '! Invalid .mat file, no "vesselBoxes" variable found !';
                    end
                end
            else
                app.Label.Text = '! No image file has been loaded !';
            end
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
            if app.imageLoaded && app.vesselBoxes.num>0
                app.Label.Text = 'Waiting for re-drawn line...';
                cv = app.currentVessel;
              
                %get user's screen size to create a large figure window
                screenSize = get(groot,'ScreenSize');
                left = screenSize(3)*0.2;
                bottom = screenSize(4)*0.1;
                width = screenSize(3)*0.6;
                height = screenSize(4)*0.8;
                
                %create new figure window for redrawing a vessel box
                drawfigure = figure('Name','draw vessel box','NumberTitle','off');
                drawaxis = axes(drawfigure);
                imshow(app.meanImage,'Parent',drawaxis);
                hold on
                set(drawfigure,'Position',[left bottom width height]);
                set(drawaxis,'OuterPosition',[0 0 1 1]);
                title(drawaxis,'Draw a box for a vessel edge segment (double-click starting vertex to finish)');
                
                %draw the currently-selected vessel boxes made so far
                n = app.vesselBoxes.num;
                color = hsv(n);
                plot(app.vesselBoxes.x(cv,:),app.vesselBoxes.y(cv,:),'Color',color(cv,:),'Parent',drawaxis,'LineWidth',0.5);
                drawnow
                
                %have user draw a new ROI polygon
                try
                    [~, x, y] = roipoly;
                catch %if roipoly figure was exited incorrectly
                    app.Label.Text = '"Add vessel" exited';
                    return
                end
                close(drawfigure)
                if exist('x','var') && isempty(x) %if no ROI is drawn
                    app.Label.Text = 'no line drawn';
                    return;
                end
                if length(x)~=5 % 5th value is the same as the 1st, so essentially this is just 4 points
                    app.Label.Text = '! no vessel box added -- vessel box must be exactly 4 points !';
                    return;
                end

                %replace ROI in full list
                app.vesselBoxes.x(cv,:) = x;
                app.vesselBoxes.y(cv,:) = y;
                
                app.Label.Text = '';
                app.updateUIAxis;

            else
                app.Label.Text = '! Image file must be loaded with at least one drawn vessel box !';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %shift all vessel boxes up
            app.vesselBoxes.y = app.vesselBoxes.y - 1;
            app.updateUIAxis;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %shift all vessel boxes down
            app.vesselBoxes.y = app.vesselBoxes.y + 1;
            app.updateUIAxis;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %shift all vessel boxes left
            app.vesselBoxes.x = app.vesselBoxes.x - 1;
            app.updateUIAxis;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %shift all vessel boxes right
            app.vesselBoxes.x = app.vesselBoxes.x + 1;
            app.updateUIAxis;
        end

        % Value changed function: VesselDropDown
        function VesselDropDownValueChanged(app, event)
            %set new "current vessel"
            app.currentVessel = str2double(app.VesselDropDown.Value);
            updateUIAxis(app)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %rotate all vessel boxes 10 deg clockwise (CW)
            centerx = app.imSize(2)/2;
            centery = app.imSize(1)/2;
            vbc_x = app.vesselBoxes.x - centerx;
            vbc_y = app.vesselBoxes.y - centery;
            vbc_r = sqrt(vbc_x.^2 + vbc_y.^2);
            vbx_a = atan2(vbc_y,vbc_x) + deg2rad(1);
            vbc_x = vbc_r.*cos(vbx_a);
            vbc_y = vbc_r.*sin(vbx_a);
            app.vesselBoxes.x = vbc_x + centerx;
            app.vesselBoxes.y = vbc_y + centery;

            updateUIAxis(app)
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %rotate all vessel boxes 10 deg counter-clockwise (CCW)
            centerx = app.imSize(2)/2;
            centery = app.imSize(1)/2;
            vbc_x = app.vesselBoxes.x - centerx;
            vbc_y = app.vesselBoxes.y - centery;
            vbc_r = sqrt(vbc_x.^2 + vbc_y.^2);
            vbx_a = atan2(vbc_y,vbc_x) - deg2rad(1);
            vbc_x = vbc_r.*cos(vbx_a);
            vbc_y = vbc_r.*sin(vbx_a);
            app.vesselBoxes.x = vbc_x + centerx;
            app.vesselBoxes.y = vbc_y + centery;

            updateUIAxis(app)
        end

        % Value changed function: OuterPixelsEditField
        function OuterPixelsEditFieldValueChanged(app, event)
            updateUIAxis(app)
        end

        % Button pushed function: bLoadOxygenationMapButton
        function bLoadOxygenationMapButtonPushed(app, event)
            app.oxygenationLoaded = false;
            app.toggleoxygenationCheckBox.Visible = "off";
            app.toggleoxygenationCheckBox.Value = 0;

            [file, path] = uigetfile(fullfile(app.dir,'*.tif'),'Choose .tif file to display vessel oxygenation');
            
            if exist(fullfile(path,file),'file')
                source = fullfile(path,file);

                app.Label.Text = 'Getting file info...';
                info = imfinfo(source);
                im_size = [info(1).Height, info(1).Width, length(info)];

                if im_size(3)==1
                    %load image
                    mI = imread(source,1);

                    %store oxygenation image
                    app.oxyImage = mI;
                    app.oxygenationLoaded = true;
                    app.toggleoxygenationCheckBox.Visible = "on";
                    app.toggleoxygenationCheckBox.Value = 1;
                    app.updateUIAxis;

                elseif im_size(3)==2
                    mI = zeros([im_size(1:2) 3]);
                    chs = [1 3];
                    for f = 1:2
                        mI(:,:,chs(f)) = imread(source,f);
                    end
                    app.Label.Text = '';

                    app.oxyImage = mI;
                    app.oxygenationLoaded = true;
                    app.toggleoxygenationCheckBox.Visible = "on";
                    app.toggleoxygenationCheckBox.Value = 1;
                    app.updateUIAxis;

                elseif im_size(3)==3
                    mI = nan([im_size(1:2) 3]);
                    for f = 1:3
                        mI(:,:,f) = imread(source,f);
                    end
                    app.Label.Text = '';
    
                    app.oxyImage = mI;
                    app.oxygenationLoaded = true;
                    app.toggleoxygenationCheckBox.Visible = "on";
                    app.toggleoxygenationCheckBox.Value = 1;
                    app.updateUIAxis;

                else
                    app.Label.Text = 'The selected .tif file only contains many frames and is not recognized as a valid oxygenation map.';
                end

            end
        end

        % Value changed function: toggleoxygenationCheckBox
        function toggleoxygenationCheckBoxValueChanged(app, event)
            app.oxygenationLoaded = app.toggleoxygenationCheckBox.Value;
            app.updateUIAxis;
        end

        % Value changed function: VesselTypeDropDown
        function VesselTypeDropDownValueChanged(app, event)
            value = app.VesselTypeDropDown.Value;
            app.vesselBoxes.type{app.currentVessel} = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'support', 'vesselWidthDemo2.png');
            app.GetVesselWidthsButton = uibutton(app.UIFigure, 'push');
            app.GetVesselWidthsButton.ButtonPushedFcn = createCallbackFcn(app, @GetVesselWidthsButtonPushed, true);
            app.GetVesselWidthsButton.Text = '3. Get Vessel Widths';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.AddVesselButton = uibutton(app.UIFigure, 'push');
            app.AddVesselButton.ButtonPushedFcn = createCallbackFcn(app, @AddVesselButtonPushed, true);
            app.AddVesselButton.Text = '2. Add Vessel';
            app.EditCurrentVesselPanel = uipanel(app.UIFigure);
            app.EditCurrentVesselPanel.Title = 'Edit Current Vessel';
            app.VesselDropDown = uidropdown(app.EditCurrentVesselPanel);
            app.VesselDropDown.Items = {};
            app.VesselDropDown.ValueChangedFcn = createCallbackFcn(app, @VesselDropDownValueChanged, true);
            app.VesselDropDown.Value = {};
            app.DeleteButton = uibutton(app.EditCurrentVesselPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.RedrawButton = uibutton(app.EditCurrentVesselPanel, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Text = 'Redraw';
            app.VesselTypeDropDownLabel = uilabel(app.EditCurrentVesselPanel);
            app.VesselTypeDropDownLabel.Text = 'Vessel Type';
            app.VesselTypeDropDown = uidropdown(app.EditCurrentVesselPanel);
            app.VesselTypeDropDown.Items = {'?', 'Artery', 'Vein'};
            app.VesselTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @VesselTypeDropDownValueChanged, true);
            app.VesselTypeDropDown.Value = '?';
            app.LoadImageFileButton = uibutton(app.UIFigure, 'push');
            app.LoadImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageFileButtonPushed, true);
            app.LoadImageFileButton.Text = '1. Load Image File';
            app.cLoadPreviousVesselsButton = uibutton(app.UIFigure, 'push');
            app.cLoadPreviousVesselsButton.ButtonPushedFcn = createCallbackFcn(app, @cLoadPreviousVesselsButtonPushed, true);
            app.cLoadPreviousVesselsButton.Text = '1c. Load Previous Vessels';
            app.AdjustAllvesselsPanel = uipanel(app.UIFigure);
            app.AdjustAllvesselsPanel.Title = 'Adjust All vessels';
            app.Button = uibutton(app.AdjustAllvesselsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'support', 'icons', 'icon_up.png');
            app.Button.Text = '';
            app.Button_5 = uibutton(app.AdjustAllvesselsPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'support', 'icons', 'icon_down.png');
            app.Button_5.Text = '';
            app.Button_3 = uibutton(app.AdjustAllvesselsPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'support', 'icons', 'icon_right.png');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.AdjustAllvesselsPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'support', 'icons', 'icon_left.png');
            app.Button_4.Text = '';
            app.Button_6 = uibutton(app.AdjustAllvesselsPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'support', 'icons', 'icon_cw.png');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.AdjustAllvesselsPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'support', 'icons', 'icon_ccw.png');
            app.Button_7.Text = '';
            app.OuterPixelsEditFieldLabel = uilabel(app.UIFigure);
            app.OuterPixelsEditFieldLabel.Text = 'Outer Pixels';
            app.OuterPixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterPixelsEditField.Limits = [1 50];
            app.OuterPixelsEditField.RoundFractionalValues = 'on';
            app.OuterPixelsEditField.ValueChangedFcn = createCallbackFcn(app, @OuterPixelsEditFieldValueChanged, true);
            app.OuterPixelsEditField.Value = 5;
            app.BinFramesEditFieldLabel = uilabel(app.UIFigure);
            app.BinFramesEditFieldLabel.Text = 'Bin Frames';
            app.BinFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinFramesEditField.Limits = [1 1000];
            app.BinFramesEditField.RoundFractionalValues = 'on';
            app.BinFramesEditField.Value = 1;
            app.bLoadOxygenationMapButton = uibutton(app.UIFigure, 'push');
            app.bLoadOxygenationMapButton.ButtonPushedFcn = createCallbackFcn(app, @bLoadOxygenationMapButtonPushed, true);
            app.bLoadOxygenationMapButton.Text = '1b. Load Oxygenation Map';
            app.toggleoxygenationCheckBox = uicheckbox(app.UIFigure);
            app.toggleoxygenationCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggleoxygenationCheckBoxValueChanged, true);
            app.toggleoxygenationCheckBox.Visible = 'off';
            app.toggleoxygenationCheckBox.Text = 'toggle oxygenation';
            app.fittypeDropDownLabel = uilabel(app.UIFigure);
            app.fittypeDropDownLabel.Text = 'fit type';
            app.fittypeDropDown = uidropdown(app.UIFigure);
            app.fittypeDropDown.Items = {'gaussian', '2-term gaussian'};
            app.fittypeDropDown.Value = 'gaussian';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getVesselWidths

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end